classdef VIStudio_ClassificationTrainingOption < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VIStudio_ClassificationTrainingOptionUIFigure  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        SolverDropDown                  matlab.ui.control.DropDown
        SolverDropDownLabel             matlab.ui.control.Label
        MaxEpochsEditField              matlab.ui.control.NumericEditField
        MaxEpochsEditFieldLabel         matlab.ui.control.Label
        MiniBatchSizeEditField          matlab.ui.control.NumericEditField
        MiniBatchSizeEditFieldLabel     matlab.ui.control.Label
        ValidationDataDropDown          matlab.ui.control.DropDown
        ValidationDataDropDownLabel     matlab.ui.control.Label
        ValidationFrequencyEditField    matlab.ui.control.NumericEditField
        ValidationFrequencyEditFieldLabel  matlab.ui.control.Label
        VerboseCheckBox                 matlab.ui.control.CheckBox
        VerboseLabel                    matlab.ui.control.Label
        PlotsDropDown                   matlab.ui.control.DropDown
        PlotsLabel                      matlab.ui.control.Label
        ShuffleDropDown                 matlab.ui.control.DropDown
        ShuffleDropDownLabel            matlab.ui.control.Label
        InitialLearnRateEditField       matlab.ui.control.NumericEditField
        InitialLearnRateEditFieldLabel  matlab.ui.control.Label
        LearnRateScheduleDropDown       matlab.ui.control.DropDown
        LearnRateScheduleDropDownLabel  matlab.ui.control.Label
        LearnRateDropPeriodEditField    matlab.ui.control.NumericEditField
        LearnRateDropPeriodEditFieldLabel  matlab.ui.control.Label
        LearnRateDropFactorEditField    matlab.ui.control.NumericEditField
        LearnRateDropFactorEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % Description
        
    end
    
    methods (Access = public)
        
        function PositionSetFcn(app)
            mainAppPos = app.mainApp.UIFigure.Position;
            app.VIStudio_ClassificationTrainingOptionUIFigure.Position(1) = mainAppPos(1) + mainAppPos(3) ;
            app.VIStudio_ClassificationTrainingOptionUIFigure.Position(2) = mainAppPos(2);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.mainApp = mainApp;
            PositionSetFcn(app)
        end

        % Close request function: 
        % VIStudio_ClassificationTrainingOptionUIFigure
        function VIStudio_ClassificationTrainingOptionUIFigureCloseRequest(app, event)
            app.mainApp.TrainingOptionsButton.Value = false;
            app.mainApp.TrainingOptionApp = [];
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VIStudio_ClassificationTrainingOptionUIFigure = uifigure('Visible', 'off');
            app.VIStudio_ClassificationTrainingOptionUIFigure.Name = 'VIStudio_Classification Training Option';
            app.VIStudio_ClassificationTrainingOptionUIFigure.CloseRequestFcn = createCallbackFcn(app, @VIStudio_ClassificationTrainingOptionUIFigureCloseRequest, true);
            app.LearnRateDropFactorEditFieldLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.LearnRateDropFactorEditFieldLabel.Text = 'LearnRateDropFactor';
            app.LearnRateDropFactorEditField = uieditfield(app.VIStudio_ClassificationTrainingOptionUIFigure, 'numeric');
            app.LearnRateDropFactorEditField.Limits = [0 1];
            app.LearnRateDropFactorEditField.Tag = 'TrainingOptions';
            app.LearnRateDropFactorEditField.Value = 1;
            app.LearnRateDropPeriodEditFieldLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.LearnRateDropPeriodEditFieldLabel.Text = 'LearnRateDropPeriod';
            app.LearnRateDropPeriodEditField = uieditfield(app.VIStudio_ClassificationTrainingOptionUIFigure, 'numeric');
            app.LearnRateDropPeriodEditField.Limits = [0 Inf];
            app.LearnRateDropPeriodEditField.RoundFractionalValues = 'on';
            app.LearnRateDropPeriodEditField.Tag = 'TrainingOptions';
            app.LearnRateDropPeriodEditField.Value = 10;
            app.LearnRateScheduleDropDownLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.LearnRateScheduleDropDownLabel.Text = 'LearnRateSchedule';
            app.LearnRateScheduleDropDown = uidropdown(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.LearnRateScheduleDropDown.Items = {'none', 'piecewise'};
            app.LearnRateScheduleDropDown.Tag = 'TrainingOptions';
            app.LearnRateScheduleDropDown.Value = 'none';
            app.InitialLearnRateEditFieldLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.InitialLearnRateEditFieldLabel.Text = 'InitialLearnRate';
            app.InitialLearnRateEditField = uieditfield(app.VIStudio_ClassificationTrainingOptionUIFigure, 'numeric');
            app.InitialLearnRateEditField.Limits = [0 Inf];
            app.InitialLearnRateEditField.Tag = 'TrainingOptions';
            app.InitialLearnRateEditField.Value = 0.01;
            app.ShuffleDropDownLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.ShuffleDropDownLabel.Text = 'Shuffle';
            app.ShuffleDropDown = uidropdown(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.ShuffleDropDown.Items = {'once', 'never', 'every-epoch'};
            app.ShuffleDropDown.Tag = 'TrainingOptions';
            app.ShuffleDropDown.Value = 'once';
            app.PlotsLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.PlotsLabel.Text = 'Plots';
            app.PlotsDropDown = uidropdown(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.PlotsDropDown.Items = {'training-progress', 'none'};
            app.PlotsDropDown.Tag = 'TrainingOptions';
            app.PlotsDropDown.Value = 'training-progress';
            app.VerboseLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.VerboseLabel.Text = 'Verbose';
            app.VerboseCheckBox = uicheckbox(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.VerboseCheckBox.Tag = 'TrainingOptions';
            app.VerboseCheckBox.Text = '';
            app.ValidationFrequencyEditFieldLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.ValidationFrequencyEditFieldLabel.Text = 'ValidationFrequency';
            app.ValidationFrequencyEditField = uieditfield(app.VIStudio_ClassificationTrainingOptionUIFigure, 'numeric');
            app.ValidationFrequencyEditField.Limits = [0 Inf];
            app.ValidationFrequencyEditField.RoundFractionalValues = 'on';
            app.ValidationFrequencyEditField.Tag = 'TrainingOptions';
            app.ValidationFrequencyEditField.Value = 10;
            app.ValidationDataDropDownLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.ValidationDataDropDownLabel.Text = 'ValidationData';
            app.ValidationDataDropDown = uidropdown(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.ValidationDataDropDown.Items = {'[]'};
            app.ValidationDataDropDown.Tag = 'TrainingOptions';
            app.ValidationDataDropDown.Placeholder = '[]';
            app.ValidationDataDropDown.Value = '[]';
            app.MiniBatchSizeEditFieldLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.MiniBatchSizeEditFieldLabel.Text = 'MiniBatchSize';
            app.MiniBatchSizeEditField = uieditfield(app.VIStudio_ClassificationTrainingOptionUIFigure, 'numeric');
            app.MiniBatchSizeEditField.Limits = [0 Inf];
            app.MiniBatchSizeEditField.RoundFractionalValues = 'on';
            app.MiniBatchSizeEditField.Tag = 'TrainingOptions';
            app.MiniBatchSizeEditField.Value = 32;
            app.MaxEpochsEditFieldLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.MaxEpochsEditFieldLabel.Text = 'MaxEpochs';
            app.MaxEpochsEditField = uieditfield(app.VIStudio_ClassificationTrainingOptionUIFigure, 'numeric');
            app.MaxEpochsEditField.Limits = [0 Inf];
            app.MaxEpochsEditField.RoundFractionalValues = 'on';
            app.MaxEpochsEditField.Tag = 'TrainingOptions';
            app.MaxEpochsEditField.Value = 10;
            app.SolverDropDownLabel = uilabel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.VIStudio_ClassificationTrainingOptionUIFigure);
            app.SolverDropDown.Items = {'sgdm', 'rmsprop', 'adam', 'lbfgs'};
            app.SolverDropDown.Tag = 'TrainingOptions';
            app.SolverDropDown.Value = 'sgdm';
            app.Panel = uipanel(app.VIStudio_ClassificationTrainingOptionUIFigure);
            % Show the figure after all components are created
            app.VIStudio_ClassificationTrainingOptionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIStudio_ClassificationTrainingOption(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VIStudio_ClassificationTrainingOptionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.VIStudio_ClassificationTrainingOptionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VIStudio_ClassificationTrainingOptionUIFigure)
        end
    end
end